classdef UI_2020b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        matButton              matlab.ui.control.Button
        matTextArea            matlab.ui.control.TextArea
        matTextAreaLabel       matlab.ui.control.Label
        tifimageTextArea       matlab.ui.control.TextArea
        tifimageTextAreaLabel  matlab.ui.control.Label
        tifimageButton         matlab.ui.control.Button
        UIAxes_output          matlab.ui.control.UIAxes
        UIAxes_input           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        mat_file = ' ';
        network
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('./no_input.jpg','Parent', app.UIAxes_input);
            imshow('./no_output.jpg','Parent', app.UIAxes_output);
        end

        % Button pushed function: tifimageButton
        function tifimageButtonPushed(app, event)
            if app.mat_file ~= ' '% if .mat檔案不=空白
                [file,path] = uigetfile('*.tif');  %則open a tif file
                if ischar(file)%如果是file類型則執行，否則沒有動作
                    app.tifimageTextArea.Value = file;%輸入file路徑到Textarea區域，並顯示
                    app.tifimageTextArea.FontColor = 'k';% k為表示字體顏色黑色,Textarea顯示字體顏色為黑色
                    full_path = strcat(path,file);%合併路徑與影像檔名
                    imshow(full_path,'Parent', app.UIAxes_input);%顯示輸入的圖片到Axesinput欄位
                    
                    test_image = imread(full_path);
                    C = semanticseg(test_image, app.network.net);
                    B = labeloverlay(test_image, C, 'Colormap', 'autumn', 'Transparency', 0.25);
                    %這邊是transfer.m的程式碼，轉出結果影像
                    imshow(B,'Parent', app.UIAxes_output);%顯示結果影像到Axesoutput欄位
                end
            else%否則出現紅色警語在textarea
                app.tifimageTextArea.Value = '先載入.mat檔案！！！';%如果沒有輸入mat,則輸入textarea並顯示這行文字
                app.tifimageTextArea.FontColor = 'r';%Textarea顯示字體顏色為紅色
            end
        end

        % Button pushed function: matButton
        function matButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if ischar(file)%檢查是否為file檔案
                app.mat_file = file;
                app.matTextArea.Value = app.mat_file;
                app.matTextArea.FontColor = 'k';
                app.network = load(strcat(path,file));
            else%如果不是file檔
                app.mat_file = ' ';%則顯示空白
                app.matTextArea.Value = '請選擇.mat檔案！！！';%並在Textarea顯示這行警語
                app.matTextArea.FontColor = 'r';%紅色
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_input = uiaxes(app.UIFigure);
            title(app.UIAxes_input, 'Input')
            app.UIAxes_output = uiaxes(app.UIFigure);
            title(app.UIAxes_output, 'Output')
            app.tifimageButton = uibutton(app.UIFigure, 'push');
            app.tifimageButton.ButtonPushedFcn = createCallbackFcn(app, @tifimageButtonPushed, true);
            app.tifimageButton.Text = '載入.tif的image';
            app.tifimageTextAreaLabel = uilabel(app.UIFigure);
            app.tifimageTextAreaLabel.Text = '載入.tif的image';
            app.tifimageTextArea = uitextarea(app.UIFigure);
            app.tifimageTextArea.Value = {'無載入任何檔案'};
            app.matTextAreaLabel = uilabel(app.UIFigure);
            app.matTextAreaLabel.Text = '載入.mat訓練檔';
            app.matTextArea = uitextarea(app.UIFigure);
            app.matTextArea.Value = {'無載入任何檔案'};
            app.matButton = uibutton(app.UIFigure, 'push');
            app.matButton.ButtonPushedFcn = createCallbackFcn(app, @matButtonPushed, true);
            app.matButton.Text = '載入.mat訓練檔';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_2020b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end